classdef GUI_projekt_alt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        KnSwitch                     matlab.ui.control.Switch
        AlderrEditFieldLabel         matlab.ui.control.Label
        AlderrEditField              matlab.ui.control.NumericEditField
        StartButton                  matlab.ui.control.Button
        GUItilprsentationafCRFLabel  matlab.ui.control.Label
        AngivalderogknfortestpersonLabel  matlab.ui.control.Label
        StartoptagelseLabel          matlab.ui.control.Label
        OptagelsestatusLampLabel     matlab.ui.control.Label
        OptagelsestatusLamp          matlab.ui.control.Lamp
        StatuslampenvillyserdunderoptagelseLabel  matlab.ui.control.Label
        OpsttestpersonmedelektrodersamtaccelerometerLabel  matlab.ui.control.Label
        CenterPanel                  matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        VisualiseringafetudsnitafdetoptagedefysiologiskdataLabel  matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        ACamplitudeEditFieldLabel    matlab.ui.control.Label
        ACamplitudeEditField         matlab.ui.control.NumericEditField
        ResultaterLabel              matlab.ui.control.Label
        CRFEditFieldLabel            matlab.ui.control.Label
        CRFEditField                 matlab.ui.control.NumericEditField
        VurderingafCRFLabel          matlab.ui.control.Label
        HentdataButton               matlab.ui.control.Button
        FysiskformEditFieldLabel     matlab.ui.control.Label
        FysiskformEditField          matlab.ui.control.EditField
        Label                        matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        Label_2                      matlab.ui.control.Label
        gLabel                       matlab.ui.control.Label
        mliltprkgminutLabel          matlab.ui.control.Label
        GaugeLabel                   matlab.ui.control.Label
        Gauge                        matlab.ui.control.Gauge
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    methods (Access = private)
        
        function results = func(app)
            Test_BLE
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                %Nedenstående sker, når der trykkes på "Start"
                app.OptagelsestatusLamp.Color = 'r'; %Lampe bliver rød 
                
                load('N51_rest.mat') % data på den ønskede testperson
                down_sample_coeffecient = 5; %koefficient til downsampling af dataet fra 5000 Hz til 1000 Hz

                data_ekg = b1((2*10^5):(length(b1)-(2*10^5)),5);  % skærer 200000 samples fra hver ende i EKG-data
                data_skg = b1((2*10^5):(length(b1)-(2*10^5)),2);  % skærer 200000 samples fra hver ende i SKG-data

                data_skg = (-0.0176*1.1).*data_skg; %ganger correction faktor på det optagede SKG-data
                b2 = data_skg(1:down_sample_coeffecient:end); %downsampler SKG
                b3 = data_ekg(1:down_sample_coeffecient:end); %downsampler EKG
                b4 = zeros(length(b3),2);

                b4(:,2) = b2.*100000; %ganger SKG-dataet med 100000 
                b4(:,1) = b3.*10000; %ganger EKG-dataet med 10000

                interval_length = 3400; %definerer interval-længden til at være 3400
                L = 2*interval_length;

                s = serial('/dev/cu.SLAB_USBtoUART');  %opretter den serielle port
                s.baudrate = 115200;       % definerer baudrate til 115200
                s.InputBufferSize = L;     % opsætter "InputBufferSize" 
                s.OutputBufferSize = L;    % opsætter "OutputBufferSize"   

                
                try
                    fopen(s)
                catch
                    disp(lasterr)
                return
                end
                
                %Nedenstående loop sender intervaller af hhv. EKG og SKG 25
                %gange. 
                for i = 1:25
                    inc = (interval_length/2)*i; %Definerer den nedre grænse i intervallet
                    anc = (interval_length/2)+(interval_length/2)*i-1; %Definerer den øvre grænse i intervallet

                    transf_data = cat(1, b4(inc:anc,1), b4(inc:anc,2)); %sætter to matricer sammen til ét array
                    transf_data = int16(transf_data);  

                    fwrite(s,transf_data,'int16'); 
                    pause(1)
                end
                
                app.OptagelsestatusLamp.Color = 'g'; %lampe bliver grøn efter optagelse
                app.Lamp.Color = 'g'; %lampe lyser grønt for at indikere at resultater er klar
               
        end

        % Button pushed function: HentdataButton
        function HentdataButtonPushed(app, event)
                s = serial('/dev/cu.SLAB_USBtoUART'); %den serielle port  
                
                %Søger efter BLE-enheder og opretter forbindelse til "ESP
                %BLE server enhed" hvis denne findes
                allBLE_list = blelist("Timeout",10,"Name","ESP");
                estab_connec = ble("ESP BLE server enhed");
                app.Lamp.Color = 'c'; %lampe lyser grøn, hvis ovenstående BLE-forbindelse lykkedes
                                            
                %definition af characteristics i den perifere enhed 
                charac_ekg = characteristic(estab_connec,"1700","1A00"); %EKG-data: Parantesen angiver UUID for den characteristic det ønskes at tilgå.
                charac_skg = characteristic(estab_connec,"1700","2A00"); %SKG-data: Parantesen angiver UUID for den characteristic det ønskes at tilgå.
                
                %Definere variable der bruges i efterfølgende to while-loops 
                charac_ekg_data = NaN(1,4);
                charac_skg_data = NaN(1,4);
                raw_ekg_data = NaN(600,4);
                raw_skg_data = NaN(300,4);
                
                i=1;
                
                %Henter EKG-data via BLE-kommunikation og plotter løbende
                %det hentede data på figuren, der er synlig i GUI'en
                while (i < 601)        
                %EKG 
                charac_ekg_data = read(charac_ekg); %læser via BLE data fra den characteristic hvor det ønskede data ligger
                raw_ekg_data(i,:) = charac_ekg_data(1,:); %rå data hentet fra ESP32 
                LSB_ekg = raw_ekg_data(i,1);    % kolonne 1 fyldes op med LSB 
                MB1_ekg = raw_ekg_data(i,2);    % kolonne 2 fyldes op med medium
                MB2_ekg = raw_ekg_data(i,3);    % kolonne 3 fyldes op med medium 
                MSB_ekg = raw_ekg_data(i,4);    % kolonne 4 fyldes op med MSB 
    
                received_ekg_data = ((2^24)*MSB_ekg)+((2^16)*MB2_ekg)+((2^8)*MB1_ekg) + LSB_ekg; %sammensætning af det hentede data 
    
                dataEKGArray(i,:) = (received_ekg_data-10000)/100000; %ligger data i array (minusser med de 10000 som ligges til dataet i c++)
                plot(app.UIAxes,dataEKGArray); %Plotter EKG-data på grafen løbende som det modtages 
                
                i = i +1; %counter 
                end 
                
                k=1;
                while (k < 301)  
                %SKG 
                charac_skg_data = read(charac_skg); %læser via BLE data fra den characteristic hvor det ønskede data ligger
                raw_skg_data(k,:) = charac_skg_data(1,:); %rå data hentet fra ESP32 
                LSB_skg = raw_skg_data(k,1);    % kolonne 1 fyldes op med LSB 
                MB1_skg = raw_skg_data(k,2);    % kolonne 2 fyldes op med medium
                MB2_skg = raw_skg_data(k,3);    % kolonne 3 fyldes op med medium 
                MSB_skg = raw_skg_data(k,4);    % kolonne 4 fyldes op med MSB 
    
                received_skg_data = ((2^24)*MSB_skg)+((2^16)*MB2_skg)+((2^8)*MB1_skg) + LSB_skg; %sammensætning af det hentede data  
    
                dataSKGArray(k,:) = (received_skg_data-10000)/100000;  %ligger data i array (minusser med de 10000 som ligges til dataet i c++)
   
                 plot(app.UIAxes2,dataSKGArray);%Plotter SKG-data på grafen løbende som det modtages

                k = k +1; %counter 
                end     
               
                pause(1)
                app.Lamp.Color = 'g'; %lampe lyser grønt når graferne er opdateret færdig 
                
                charac_AC = characteristic(estab_connec,"1700","3A00"); %AC-data: Parantesen angiver UUID for den characteristic det ønskes at tilgå.
                charac_AC_data = read(charac_AC);%læser via BLE data fra den characteristic hvor det ønskede data ligger
                
                LSB_ac = charac_AC_data(:,1);    % kolonne 1 fyldes op med LSB 
                MSB_ac = charac_AC_data(:,2);    % kolonne 2 fyldes op med MSB 
    
                received_ac_data = (256*MSB_ac) + LSB_ac; %sammensætning af det hentede data 
                a = received_ac_data/100000; %ligger data i array (minusser med de 10000 som ligges til dataet i c++)
            
                fclose(s) %lukker den serielle port 
                
                %%%%% Viser værdien for AC-amplituden, VO2max og CRF i displayet          
                app.ACamplitudeEditField.Value = a;
                app.CRFEditField.Value = (337.9*app.ACamplitudeEditField.Value)+30.0; %matematisk model
                     
         %%%%% Kategorisering af fysisk form
         value = app.KnSwitch.Value; %"value" =  "mand" eller "kvinde" (vælges af brugeren af GUI'en)
         A = app.AlderrEditField.Value; %"A" =  alder (vælges af brugeren af GUI'en)
         CRF = app.CRFEditField.Value; %definerer resultatet i variablen "CRF", som bruges i switch-case
         
         %Switch case til kategorisering af CRF 
         %følgende bruges som input parametre: køn, alder og CRF 
         switch value
             case 'Kvinde'
                if (20<=A) && (A<=24)
                    if (CRF<27)
                        app.FysiskformEditField.Value = 'Meget dårlig';
                        app.Gauge.Value = 10;
                    elseif (27<=CRF) && (CRF<=31)
                        app.FysiskformEditField.Value = 'Dårlig';
                        app.Gauge.Value = 26;
                    elseif (32<=CRF) && (CRF<=36)
                        app.FysiskformEditField.Value = 'Rimelig';
                        app.Gauge.Value = 39;
                    elseif (37<=CRF) && (CRF<=41)
                        app.FysiskformEditField.Value = 'Gennemsnitlig';
                        app.Gauge.Value = 50;
                    elseif (42<=CRF) && (CRF<=46)
                        app.FysiskformEditField.Value = 'God';
                        app.Gauge.Value = 66;
                    elseif (47<=CRF) && (CRF<=51)
                        app.FysiskformEditField.Value = 'Meget god';
                        app.Gauge.Value = 82;
                    elseif (51<CRF)
                        app.FysiskformEditField.Value = 'Fremragende';
                        app.Gauge.Value = 98; 
                    end
                    
                elseif (25<=A) && (A<=29)
                    if (CRF<26)
                        app.FysiskformEditField.Value = 'Meget dårlig';
                        app.Gauge.Value = 10;
                    elseif (26<=CRF) && (CRF<=30)
                        app.FysiskformEditField.Value = 'Dårlig';
                        app.Gauge.Value = 26;
                    elseif (31<=CRF) && (CRF<=35)
                        app.FysiskformEditField.Value = 'Rimelig';
                        app.Gauge.Value = 39;
                    elseif (36<=CRF) && (CRF<=40)
                        app.FysiskformEditField.Value = 'Gennemsnitlig';
                        app.Gauge.Value = 50;
                    elseif (41<=CRF) && (CRF<=44)
                        app.FysiskformEditField.Value = 'God';
                        app.Gauge.Value = 66;
                    elseif (45<=CRF) && (CRF<=49)
                        app.FysiskformEditField.Value = 'Meget god';
                        app.Gauge.Value = 82;
                    elseif (49<CRF)
                        app.FysiskformEditField.Value = 'Fremragende';
                        app.Gauge.Value = 98; 
                    end      
                    
                elseif (30<=A) && (A<=34)
                    if (CRF<25)
                        app.FysiskformEditField.Value = 'Meget dårlig';
                        app.Gauge.Value = 10;
                    elseif (25<=CRF) && (CRF<=29)
                        app.FysiskformEditField.Value = 'Dårlig';
                        app.Gauge.Value = 26;
                    elseif (30<=CRF) && (CRF<=33)
                        app.FysiskformEditField.Value = 'Rimelig';
                        app.Gauge.Value = 39;
                    elseif (34<=CRF) && (CRF<=37)
                        app.FysiskformEditField.Value = 'Gennemsnitlig';
                        app.Gauge.Value = 50;
                    elseif(38<=CRF) && (CRF<=42)
                        app.FysiskformEditField.Value = 'God';
                        app.Gauge.Value = 66;
                    elseif (43<=CRF) && (CRF<=46)
                        app.FysiskformEditField.Value = 'Meget god';
                        app.Gauge.Value = 82;
                    elseif (46<CRF)
                        app.FysiskformEditField.Value = 'Fremragende';
                        app.Gauge.Value = 98;  
                    end
                    
                elseif (35<=A) && (A<=39)
                    if (CRF<24)
                        app.FysiskformEditField.Value = 'Meget dårlig';
                        app.Gauge.Value = 10;
                    elseif (24<=CRF) && (CRF<=27)
                        app.FysiskformEditField.Value = 'Dårlig';
                        app.Gauge.Value = 26;
                    elseif (28<=CRF) && (CRF<=31)
                        app.FysiskformEditField.Value = 'Rimelig';
                        app.Gauge.Value = 39;
                    elseif (32<=CRF) && (CRF<=35)
                        app.FysiskformEditField.Value = 'Gennemsnitlig';
                        app.Gauge.Value = 50;
                    elseif(36<=CRF) && (CRF<=40)
                        app.FysiskformEditField.Value = 'God';
                        app.Gauge.Value = 66;
                    elseif (41<=CRF) && (CRF<=44)
                        app.FysiskformEditField.Value = 'Meget god';
                        app.Gauge.Value = 82;
                    elseif (44<CRF)
                        app.FysiskformEditField.Value = 'Fremragende';
                        app.Gauge.Value = 98;  
                    end
                    
                elseif (40<=A) && (A<=44)
                    if (CRF<22)
                        app.FysiskformEditField.Value = 'Meget dårlig';
                        app.Gauge.Value = 10;
                    elseif (22<=CRF) && (CRF<=25)
                        app.FysiskformEditField.Value = 'Dårlig';
                        app.Gauge.Value = 26;
                    elseif (26<=CRF) && (CRF<=29)
                        app.FysiskformEditField.Value = 'Rimelig';
                        app.Gauge.Value = 39;
                    elseif (30<=CRF) && (CRF<=33)
                        app.FysiskformEditField.Value = 'Gennemsnitlig';
                        app.Gauge.Value = 50;
                    elseif(34<=CRF) && (CRF<=37)
                        app.FysiskformEditField.Value = 'God';
                        app.Gauge.Value = 66;
                    elseif (38<=CRF) && (CRF<=41)
                        app.FysiskformEditField.Value = 'Meget god';
                        app.Gauge.Value = 82;
                    elseif (41<CRF)
                        app.FysiskformEditField.Value = 'Fremragende';
                        app.Gauge.Value = 98;  
                    end
                    
                 elseif (45<=A) && (A<=49)
                    if (CRF<21)
                        app.FysiskformEditField.Value = 'Meget dårlig';
                        app.Gauge.Value = 10;
                    elseif (21<=CRF) && (CRF<=23)
                        app.FysiskformEditField.Value = 'Dårlig';
                        app.Gauge.Value = 26;
                    elseif (24<=CRF) && (CRF<=27)
                        app.FysiskformEditField.Value = 'Rimelig';
                        app.Gauge.Value = 39;
                    elseif (28<=CRF) && (CRF<=31)
                        app.FysiskformEditField.Value = 'Gennemsnitlig';
                        app.Gauge.Value = 50;
                    elseif(32<=CRF) && (CRF<=35)
                        app.FysiskformEditField.Value = 'God';
                        app.Gauge.Value = 66;
                    elseif (36<=CRF) && (CRF<=38)
                        app.FysiskformEditField.Value = 'Meget god';
                        app.Gauge.Value = 82;
                    elseif (38<CRF)
                        app.FysiskformEditField.Value = 'Fremragende';
                        app.Gauge.Value = 98; 
                    end
                                          
                 elseif (50<=A) && (A<=54)
                     if (CRF<19)
                         app.FysiskformEditField.Value = 'Meget dårlig';
                         app.Gauge.Value = 10;
                     elseif (19<=CRF) && (CRF<=22)
                         app.FysiskformEditField.Value = 'Dårlig';
                         app.Gauge.Value = 26;
                     elseif (23<=CRF) && (CRF<=25)
                         app.FysiskformEditField.Value = 'Rimelig';
                         app.Gauge.Value = 39;
                     elseif (26<=CRF) && (CRF<=29)
                         app.FysiskformEditField.Value = 'Gennemsnitlig';
                         app.Gauge.Value = 50;
                     elseif (30<=CRF) && (CRF<=32)
                         app.FysiskformEditField.Value = 'God';
                         app.Gauge.Value = 66;
                     elseif (33<=CRF) && (CRF<=36)
                         app.FysiskformEditField.Value = 'Meget god';
                         app.Gauge.Value = 82;
                     elseif (36<CRF)
                         app.FysiskformEditField.Value = 'Fremragende';
                         app.Gauge.Value = 98;  
                     end              
                
                 elseif (55<=A) && (A<=59)
                     if (CRF<18)
                         app.FysiskformEditField.Value = 'Meget dårlig';
                         app.Gauge.Value = 10;
                     elseif (18<=CRF) && (CRF<=20)
                         app.FysiskformEditField.Value = 'Dårlig';
                         app.Gauge.Value = 26;
                     elseif (21<=CRF) && (CRF<=23)
                         app.FysiskformEditField.Value = 'Rimelig';
                         app.Gauge.Value = 39;
                     elseif (24<=CRF) && (CRF<=27)
                         app.FysiskformEditField.Value = 'Gennemsnitlig';
                         app.Gauge.Value = 50;
                     elseif (28<=CRF) && (CRF<=30)
                         app.FysiskformEditField.Value = 'God';
                         app.Gauge.Value = 66;
                     elseif (31<=CRF) && (CRF<=33)
                         app.FysiskformEditField.Value = 'Meget god';
                         app.Gauge.Value = 82;
                     elseif (33<CRF)
                         app.FysiskformEditField.Value = 'Fremragende';
                         app.Gauge.Value = 98; 
                     end
   
                 elseif (60<=A) && (A<=65)
                     if (CRF<16)
                         app.FysiskformEditField.Value = 'Meget dårlig';
                         app.Gauge.Value = 10;
                     elseif (16<=CRF) && (CRF<=18)
                         app.FysiskformEditField.Value = 'Dårlig';
                         app.Gauge.Value = 26;
                     elseif (19<=CRF) && (CRF<=21)
                         app.FysiskformEditField.Value = 'Rimelig';
                         app.Gauge.Value = 39;
                     elseif (22<=CRF) && (CRF<=24)
                         app.FysiskformEditField.Value = 'Gennemsnitlig';
                         app.Gauge.Value = 50;
                     elseif (25<=CRF) && (CRF<=27)
                         app.FysiskformEditField.Value = 'God';
                         app.Gauge.Value = 66;
                     elseif (28<=CRF) && (CRF<=30)
                         app.FysiskformEditField.Value = 'Meget god';
                         app.Gauge.Value = 82;
                     elseif (30<CRF)
                         app.FysiskformEditField.Value = 'Fremragende';
                         app.Gauge.Value = 98;  
                     end
                end                
             case 'Mand'                 
                  if (20<=A) && (A<=24) 
                     if (CRF<32)
                        app.FysiskformEditField.Value = 'Meget dårlig';
                        app.Gauge.Value = 10;
                     elseif (32<=CRF) && (CRF<=37)
                        app.FysiskformEditField.Value = 'Dårlig';
                        app.Gauge.Value = 26;
                    elseif (38<=CRF) && (CRF<=43)
                         app.FysiskformEditField.Value = 'Rimelig';
                         app.Gauge.Value = 39;
                    elseif (44<=CRF) && (CRF<=50)
                        app.FysiskformEditField.Value = 'Gennemsnitlig';
                        app.Gauge.Value = 50;
                    elseif (51<=CRF) && (CRF<=56)
                        app.FysiskformEditField.Value = 'God';
                        app.Gauge.Value = 66;
                    elseif (57<=CRF) && (CRF<=62)
                        app.FysiskformEditField.Value = 'Meget god';
                        app.Gauge.Value = 82;
                    elseif (62<CRF)
                        app.FysiskformEditField.Value = 'Fremragende';
                        app.Gauge.Value = 98; 
                    end
                    
                 elseif (25<=A) && (A<=29)
                    if (CRF<31)
                        app.FysiskformEditField.Value = 'Meget dårlig';
                        app.Gauge.Value = 10;
                    elseif (31<=CRF) && (CRF<=35)
                        app.FysiskformEditField.Value = 'Dårlig';
                        app.Gauge.Value = 26;
                    elseif (36<=CRF) && (CRF<=42)
                        app.FysiskformEditField.Value = 'Rimelig';
                        app.Gauge.Value = 39;
                    elseif (43<=CRF) && (CRF<=48)
                        app.FysiskformEditField.Value = 'Gennemsnitlig';
                        app.Gauge.Value = 50;
                    elseif (49<=CRF) && (CRF<=53)
                        app.FysiskformEditField.Value = 'God';
                        app.Gauge.Value = 66;
                    elseif (54<=CRF) && (CRF<=59)
                        app.FysiskformEditField.Value = 'Meget god';
                        app.Gauge.Value = 82;
                    elseif (59<CRF)
                        app.FysiskformEditField.Value = 'Fremragende';
                        app.Gauge.Value = 98;  
                    end
                    
                elseif (30<=A) && (A<=34)
                    if (CRF<29)
                        app.FysiskformEditField.Value = 'Meget dårlig';
                        app.Gauge.Value = 10;
                    elseif (29<=CRF) && (CRF<=34)
                        app.FysiskformEditField.Value = 'Dårlig';
                        app.Gauge.Value = 26;
                    elseif (35<=CRF) && (CRF<=40)
                        app.FysiskformEditField.Value = 'Rimelig';
                        app.Gauge.Value = 39;
                    elseif (41<=CRF) && (CRF<=45)
                        app.FysiskformEditField.Value = 'Gennemsnitlig';
                        app.Gauge.Value = 50;
                    elseif (46<=CRF) && (CRF<=51)
                        app.FysiskformEditField.Value = 'God';
                        app.Gauge.Value = 66;
                    elseif (52<=CRF) && (CRF<=56)
                        app.FysiskformEditField.Value = 'Meget god';
                        app.Gauge.Value = 82;
                    elseif (56<CRF)
                        app.FysiskformEditField.Value = 'Fremragende';
                        app.Gauge.Value = 98;  
                    end
          
                elseif (35<=A) && (A<=39)
                    if (CRF<28)
                        app.FysiskformEditField.Value = 'Meget dårlig';
                        app.Gauge.Value = 10;
                    elseif (28<=CRF) && (CRF<=32)
                        app.FysiskformEditField.Value = 'Dårlig';
                        app.Gauge.Value = 26;
                    elseif (33<=CRF) && (CRF<=38)
                        app.FysiskformEditField.Value = 'Rimelig';
                        app.Gauge.Value = 39;
                    elseif (39<=CRF) && (CRF<=43)
                        app.FysiskformEditField.Value = 'Gennemsnitlig';
                        app.Gauge.Value = 50;
                    elseif (44<=CRF) && (CRF<=48)
                        app.FysiskformEditField.Value = 'God';
                        app.Gauge.Value = 66;
                    elseif (49<=CRF) && (CRF<=54)
                        app.FysiskformEditField.Value = 'Meget god';
                        app.Gauge.Value = 82;
                    elseif (54<CRF)
                        app.FysiskformEditField.Value = 'Fremragende';
                        app.Gauge.Value = 98;  
                    end
                    
                elseif (40<=A) && (A<=44)
                    if (CRF<26)
                        app.FysiskformEditField.Value = 'Meget dårlig';
                        app.Gauge.Value = 10;
                    elseif (26<=CRF) && (CRF<=31)
                        app.FysiskformEditField.Value = 'Dårlig';
                        app.Gauge.Value = 26;
                    elseif (32<=CRF) && (CRF<=35)
                        app.FysiskformEditField.Value = 'Rimelig';
                        app.Gauge.Value = 39;
                    elseif (36<=CRF) && (CRF<=41)
                        app.FysiskformEditField.Value = 'Gennemsnitlig';
                        app.Gauge.Value = 50;
                    elseif (42<=CRF) && (CRF<=46)
                        app.FysiskformEditField.Value = 'God';
                        app.Gauge.Value = 66;
                    elseif (47<=CRF) && (CRF<=51)
                        app.FysiskformEditField.Value = 'Meget god';
                        app.Gauge.Value = 82;
                    elseif (51<CRF)
                        app.FysiskformEditField.Value = 'Fremragende';
                        app.Gauge.Value = 98;  
                    end
                    
                elseif (45<=A) && (A<=49)
                    if (CRF<25)
                        app.FysiskformEditField.Value = 'Meget dårlig';
                        app.Gauge.Value = 10;
                    elseif (25<=CRF) && (CRF<=29)
                        app.FysiskformEditField.Value = 'Dårlig';
                        app.Gauge.Value = 26;
                    elseif (30<=CRF) && (CRF<=34)
                        app.FysiskformEditField.Value = 'Rimelig';
                        app.Gauge.Value = 39;
                    elseif (35<=CRF) && (CRF<=39)
                        app.FysiskformEditField.Value = 'Gennemsnitlig';
                        app.Gauge.Value = 50;
                    elseif (40<=CRF) && (CRF<=43)
                        app.FysiskformEditField.Value = 'God';
                        app.Gauge.Value = 66;
                    elseif (44<=CRF) && (CRF<=48)
                        app.FysiskformEditField.Value = 'Meget god';
                        app.Gauge.Value = 82;
                    elseif (48<CRF)
                        app.FysiskformEditField.Value = 'Fremragende';
                        app.Gauge.Value = 98;  
                    end
                    
                elseif (50<=A) && (A<=54)
                    if (CRF<24)
                        app.FysiskformEditField.Value = 'Meget dårlig';
                        app.Gauge.Value = 10;
                    elseif (24<=CRF) && (CRF<=27)
                        app.FysiskformEditField.Value = 'Dårlig';
                        app.Gauge.Value = 26;
                    elseif (28<=CRF) && (CRF<=32)
                        app.FysiskformEditField.Value = 'Rimelig';
                        app.Gauge.Value = 39;
                    elseif (33<=CRF) && (CRF<=36)
                        app.FysiskformEditField.Value = 'Gennemsnitlig';
                        app.Gauge.Value = 50;
                    elseif (37<=CRF) && (CRF<=41)
                        app.FysiskformEditField.Value = 'God';
                        app.Gauge.Value = 66;
                    elseif (42<=CRF) && (CRF<=46)
                        app.FysiskformEditField.Value = 'Meget god';
                        app.Gauge.Value = 82;
                    elseif (46<CRF)
                        app.FysiskformEditField.Value = 'Fremragende';
                        app.Gauge.Value = 98; 
                    end

                elseif (55<=A) && (A<=59)
                    if (CRF<22)
                        app.FysiskformEditField.Value = 'Meget dårlig';
                        app.Gauge.Value = 10;
                    elseif (22<=CRF) && (CRF<=26)
                        app.FysiskformEditField.Value = 'Dårlig';
                        app.Gauge.Value = 26;
                    elseif (27<=CRF) && (CRF<=30)
                        app.FysiskformEditField.Value = 'Rimelig';
                        app.Gauge.Value = 39;
                    elseif (31<=CRF) && (CRF<=34)
                        app.FysiskformEditField.Value = 'Gennemsnitlig';
                        app.Gauge.Value = 50;
                    elseif (35<=CRF) && (CRF<=39)
                        app.FysiskformEditField.Value = 'God';
                        app.Gauge.Value = 66;
                    elseif (40<=CRF) && (CRF<=43)
                        app.FysiskformEditField.Value = 'Meget god';
                        app.Gauge.Value = 82;
                    elseif (43<CRF)
                        app.FysiskformEditField.Value = 'Fremragende';
                        app.Gauge.Value = 98; 
                    end

                elseif (60<=A) && (A<=65)
                       if (CRF<21)
                        app.FysiskformEditField.Value = 'Meget dårlig';
                        app.Gauge.Value = 10;
                    elseif (21<=CRF) && (CRF<=24)
                        app.FysiskformEditField.Value = 'Dårlig';
                        app.Gauge.Value = 26;
                    elseif (25<=CRF) && (CRF<=28)
                        app.FysiskformEditField.Value = 'Rimelig';
                        app.Gauge.Value = 39;
                    elseif (29<=CRF) && (CRF<=32)
                        app.FysiskformEditField.Value = 'Gennemsnitlig';
                        app.Gauge.Value = 50;
                    elseif (33<=CRF) && (CRF<=36)
                        app.FysiskformEditField.Value = 'God';
                        app.Gauge.Value = 66;
                    elseif (37<=CRF) && (CRF<=40)
                        app.FysiskformEditField.Value = 'Meget god';
                        app.Gauge.Value = 82;
                    elseif (40<CRF)
                        app.FysiskformEditField.Value = 'Fremragende';
                        app.Gauge.Value = 98;  
                       end
                  end
                    
         end
                    
             
        end

        % Value changed function: AlderrEditField
        function AlderrEditFieldValueChanged(app, event)
            value = app.AlderrEditField.Value;
            
        end

        % Value changed function: KnSwitch
        function KnSwitchValueChanged(app, event)
            value = app.KnSwitch.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {242, '1x', 245};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.KnSwitch = uiswitch(app.LeftPanel, 'slider');
            app.KnSwitch.Items = {'Kvinde', 'Mand'};
            app.KnSwitch.ValueChangedFcn = createCallbackFcn(app, @KnSwitchValueChanged, true);
            app.KnSwitch.Value = 'Kvinde';
            app.AlderrEditFieldLabel = uilabel(app.LeftPanel);
            app.AlderrEditFieldLabel.Text = {'Alder (år)'; ''};
            app.AlderrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AlderrEditField.ValueChangedFcn = createCallbackFcn(app, @AlderrEditFieldValueChanged, true);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = {'Start'; ''};
            app.GUItilprsentationafCRFLabel = uilabel(app.LeftPanel);
            app.GUItilprsentationafCRFLabel.Text = {'GUI til præsentation af CRF'; ''};
            app.AngivalderogknfortestpersonLabel = uilabel(app.LeftPanel);
            app.AngivalderogknfortestpersonLabel.Text = {'Angiv alder og køn'; ' for testperson:'};
            app.StartoptagelseLabel = uilabel(app.LeftPanel);
            app.StartoptagelseLabel.Text = 'Start optagelse ';
            app.OptagelsestatusLampLabel = uilabel(app.LeftPanel);
            app.OptagelsestatusLampLabel.Text = 'Optagelse status: ';
            app.OptagelsestatusLamp = uilamp(app.LeftPanel);
            app.StatuslampenvillyserdunderoptagelseLabel = uilabel(app.LeftPanel);
            app.StatuslampenvillyserdunderoptagelseLabel.Text = {'Tryk på "Start" når optagelse skal '; 'begynde. Status lampen vil lyse rød '; 'under optagelse af data.'};
            app.OpsttestpersonmedelektrodersamtaccelerometerLabel = uilabel(app.LeftPanel);
            app.OpsttestpersonmedelektrodersamtaccelerometerLabel.Text = {'Opsæt testperson med elektroder'; ' samt accelerometer og vær'; 'opmærksom på at "optage-enhed"'; ' samt "modtager-enhed" skal '; 'være indenfor rækkevidde '; 'af hinanden. '};
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'EKG')
            xlabel(app.UIAxes, 'Tid (ms)')
            ylabel(app.UIAxes, 'Amplitude (mV) ')
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, 'SKG')
            xlabel(app.UIAxes2, 'Tid (ms)')
            ylabel(app.UIAxes2, 'Amplitude (g)')
            app.VisualiseringafetudsnitafdetoptagedefysiologiskdataLabel = uilabel(app.CenterPanel);
            app.VisualiseringafetudsnitafdetoptagedefysiologiskdataLabel.Text = {'Visualisering af det gennemsnitslige EKG-signal samt SKG-signal'; ' i det optagede fysiologiske data'};
            app.RightPanel = uipanel(app.GridLayout);
            app.ACamplitudeEditFieldLabel = uilabel(app.RightPanel);
            app.ACamplitudeEditFieldLabel.Text = 'AC amplitude';
            app.ACamplitudeEditField = uieditfield(app.RightPanel, 'numeric');
            app.ACamplitudeEditField.ValueDisplayFormat = '%.4f';
            app.ResultaterLabel = uilabel(app.RightPanel);
            app.ResultaterLabel.Text = 'Resultater';
            app.CRFEditFieldLabel = uilabel(app.RightPanel);
            app.CRFEditFieldLabel.Text = 'CRF';
            app.CRFEditField = uieditfield(app.RightPanel, 'numeric');
            app.CRFEditField.ValueDisplayFormat = '%.1f';
            app.VurderingafCRFLabel = uilabel(app.RightPanel);
            app.VurderingafCRFLabel.Text = 'Vurdering af CRF';
            app.HentdataButton = uibutton(app.RightPanel, 'push');
            app.HentdataButton.ButtonPushedFcn = createCallbackFcn(app, @HentdataButtonPushed, true);
            app.HentdataButton.Text = 'Hent data';
            app.FysiskformEditFieldLabel = uilabel(app.RightPanel);
            app.FysiskformEditFieldLabel.Text = 'Fysisk form';
            app.FysiskformEditField = uieditfield(app.RightPanel, 'text');
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            app.Lamp = uilamp(app.RightPanel);
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = {'Når knappen nedenfor lyser grøn, '; 'er tilstrækkeligt data optaget. Tryk herefter '; 'på "Hent data", for at få resultatet  '; 'præsenteret. '};
            app.gLabel = uilabel(app.RightPanel);
            app.gLabel.Text = 'g';
            app.mliltprkgminutLabel = uilabel(app.RightPanel);
            app.mliltprkgminutLabel.Text = 'ml. ilt pr. kg. minut';
            app.GaugeLabel = uilabel(app.RightPanel);
            app.GaugeLabel.Text = '';
            app.Gauge = uigauge(app.RightPanel, 'circular');
            app.Gauge.MajorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_projekt_alt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end